classdef RapidTestAnalisis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DiagnosisTextArea        matlab.ui.control.TextArea
        DiagnosisTextAreaLabel   matlab.ui.control.Label
        TakePictureButton_2      matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        SelectPictureButton      matlab.ui.control.Button
        DeteksiButton            matlab.ui.control.Button
        RapidTestAnalyzerLabel   matlab.ui.control.Label
        IntensitasTextArea       matlab.ui.control.TextArea
        IntensitasTextAreaLabel  matlab.ui.control.Label
        CekHasilButton           matlab.ui.control.Button
        ConvertButton            matlab.ui.control.Button
        PotongButton             matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes_6                 matlab.ui.control.UIAxes
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Gambar dari Komputer
        img2 % Gambar dari Streaming
        img3 % Gambar Biner tanpa di crop
        img4 % Gambar Biner yang telah di crop
        img5 % Gambar Kanal Hijau yang telah di crop
        img6
        properti_objek
        intensitas_cahaya
        result
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TakePictureButton_2
        function TakePictureButton_2Pushed(app, event)
           %cam = ipcam("http://192.168.138.183:81/stream");
           %cam = ipcam("http://192.168.4.1:81/stream");
           %cam = ipcam("http://192.168.4.2:81/stream");
           %cam = ipcam('http://192.168.1.50:81/stream');
           cam = ipcam('http://192.168.1.100:81/stream');
           app.img = snapshot(cam);
           imshow(app.img,"Parent",app.UIAxes);
           figure
           app.img = imcrop(app.img);
        end

        % Button pushed function: SelectPictureButton
        function SelectPictureButtonPushed(app, event)
           %%Jika ingin melakukan search di Komputer
           [file, path] = uigetfile('*.*');
           if isequal(file,0)
               figure(app.UIFigure);
               return;
           end
           app.img = imread(fullfile(path,file));
           %imshow(app.img, "Parent", app.UIAxes)
           figure
           app.img = imcrop(app.img);
           imshow(app.img, "Parent", app.UIAxes)
        end

        % Button pushed function: PotongButton
        function PotongButtonPushed(app, event)
           % Jika ingin memotong gambar
           % Menentukan batas bagian yang diinginkan (misalnya, koordinat pojok kiri atas dan ukuran bagian)
           baris_mulai = 40; % Baris awal matriks
           baris_akhir = 90; % Baris akhir matriks
           kolom_mulai = 65; % Kolom awal matriks
           kolom_akhir = 75; % Kolom akhir matriks

           % Memotong bagian yang diinginkan dari gambar grayscale
           %app.img6 = app.frame(baris_mulai:baris_akhir, kolom_mulai:kolom_akhir)
           app.img = app.img(baris_mulai:baris_mulai+baris_akhir-1, kolom_akhir:kolom_akhir+kolom_mulai-1, :);
           imshow(app.img, "Parent", app.UIAxes2);
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            citra_inverted = 255 - app.img;
            imshow (citra_inverted,"Parent", app.UIAxes_5)

            hh = fspecial('motion', 5);
            bb = imfilter(citra_inverted,hh); 
            g = bb(:,:,2); % Channel Hijau yang dibutuhkan 

            imshow (g,'Parent',app.UIAxes_2);

            % Konversi gambar ke citra biner menggunakan ambang batas adaptif
            ambang_batas = graythresh(g);
 
            % Gambar biner dari konversi channel hijau dan nilai ambang batas
            app.img3 = imbinarize(g, ambang_batas);

            imshow (app.img3,'Parent',app.UIAxes_3);

        end

        % Button pushed function: DeteksiButton
        function DeteksiButtonPushed(app, event)
            % Menentukan batas bagian yang diinginkan (misalnya, koordinat pojok kiri atas dan ukuran bagian)
            baris_mulai = 10; % Baris awal matriks
            baris_akhir = 295; % Baris akhir matriks
            kolom_mulai = 35; % Kolom awal matriks
            kolom_akhir = 85; % Kolom akhir matriks

            % Memotong bagian yang diinginkan dari gambar grayscale
            app.img4 = app.img3(baris_mulai:baris_mulai+baris_akhir-1, kolom_akhir:kolom_akhir+kolom_mulai-1);
            imshow(app.img4, "Parent", app.UIAxes_6);
            
            % Mengidentifikasi properti objek dalam citra biner
            app.properti_objek = regionprops(app.img4, 'BoundingBox');

            g2 = app.img(:,:,2);

            app.img5 = g2(baris_mulai:baris_mulai+baris_akhir-1, kolom_akhir:kolom_akhir+kolom_mulai-1);

            app.IntensitasTextArea.Value = " ";

            imshow(app.img5,"Parent", app.UIAxes_4);
            hold on
            app.result = {};
            for i = 1:numel(app.properti_objek)
                bounding_box = (app.properti_objek(i).BoundingBox);
                rectangle(app.UIAxes_4,'Position', bounding_box, 'EdgeColor', 'r', 'LineWidth', 2);
                app.intensitas_cahaya = mean(bounding_box);
                Intensitas = num2str(app.intensitas_cahaya);
                if str2double(Intensitas) >=0 && str2double(Intensitas) <50
                   Hasil = "+3";
                elseif str2double(Intensitas) >=50 && str2double(Intensitas) <80
                   Hasil = "+2";
                elseif str2double(Intensitas) >=80
                   Hasil = "+1";
                end
                app.result{i,1}= char(append("Kotak",num2str(i),": ", Hasil));
                app.IntensitasTextArea.Value = app.result;
                %data = app.IntensitasTextArea.Value;
                %app.IntensitasTextArea.Value = append(data, "Kotak", num2str(i), ":", Hasil, " ", newline);
                
            end
            hold off
            
        end

        % Button pushed function: CekHasilButton
        function CekHasilButtonPushed(app, event)
            % Ambil posisi Bounding Box
            %bounding_box = [kolom_akhir, baris_mulai, kolom_mulai, baris_akhir];
            % Menentukan batas bagian yang diinginkan (misalnya, koordinat pojok kiri atas dan ukuran bagian)
            baris_mulai = 10; % Baris awal matriks
            baris_akhir = 295; % Baris akhir matriks
            kolom_mulai = 35; % Kolom awal matriks
            kolom_akhir = 85; % Kolom akhir matriks

            % Koordinat titik tengah Bounding Box
            titik_tengah_x = kolom_akhir + kolom_mulai/2;
            titik_tengah_y = baris_mulai + baris_akhir/2;

            % Koordinat pusat gambar
            pusat_gambar_x = size(app.img4, 2) / 2;
            pusat_gambar_y = size(app.img4, 1) / 2;

            % Hitung jarak antara titik tengah Bounding Box dengan pusat gambar
            jarak = sqrt((titik_tengah_x - pusat_gambar_x)^2 + (titik_tengah_y - pusat_gambar_y)^2);
            
            numofElements = num2str(numel((app.properti_objek)));
            if str2double(numofElements) > 1 
                app.DiagnosisTextArea.Value = "Positif" ;
            elseif str2double(numofElements) == 1
                % Pengkondisian berdasarkan jarak
                if jarak < 60
                % Bounding Box berada di dekat pusat gambar
                    app.DiagnosisTextArea.Value = "Negatif";
                else
                % Bounding Box berada jauh dari pusat gambar
                    app.DiagnosisTextArea.Value = "Invalid";
                end
            elseif str2double(numofElements) == 0
                app.DiagnosisTextArea.Value = "Invalid" ;
            end


%             Intensitas = str2double(app.intensitas_cahaya);
% 
%             for i = 1:numel(app.intensitas_cahaya)
%                 if Intensitas >=0 && Intensitas <50
%                    app.IntensitasTextArea.Value = "+3";
%                 elseif Intensitas >=50 && Intensitas <100
%                    app.IntensitasTextArea.Value = "+2";
%                 elseif Intensitas >=100
%                    app.IntensitasTextArea.Value = "+1";
%                 end
%             end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            cla(app.UIAxes_5);
            cla(app.UIAxes_6);
            app.img = "";
            app.img2 = "";
            app.img3 = "";
            app.img4 = "";
            title(app.UIAxes,"");
            title(app.UIAxes_2,"");
            title(app.UIAxes_3,"");
            title(app.UIAxes_4,"");
            title(app.UIAxes_5,"");
            title(app.UIAxes_6,"");
            app.DiagnosisTextArea.Value = "";
            app.IntensitasTextArea.Value = " ";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Asli')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Kanal Hijau')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Biner')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Citra Deteksi')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Warna Terbalik')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Biner Crop')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Hasil Potong')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.PotongButton = uibutton(app.UIFigure, 'push');
            app.PotongButton.ButtonPushedFcn = createCallbackFcn(app, @PotongButtonPushed, true);
            app.PotongButton.Text = 'Potong';
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.CekHasilButton = uibutton(app.UIFigure, 'push');
            app.CekHasilButton.ButtonPushedFcn = createCallbackFcn(app, @CekHasilButtonPushed, true);
            app.CekHasilButton.Text = 'Cek Hasil';
            app.IntensitasTextAreaLabel = uilabel(app.UIFigure);
            app.IntensitasTextAreaLabel.Text = 'Intensitas';
            app.IntensitasTextArea = uitextarea(app.UIFigure);
            app.IntensitasTextArea.Value = {''; ''; ''};
            app.RapidTestAnalyzerLabel = uilabel(app.UIFigure);
            app.RapidTestAnalyzerLabel.Text = 'Rapid Test Analyzer';
            app.DeteksiButton = uibutton(app.UIFigure, 'push');
            app.DeteksiButton.ButtonPushedFcn = createCallbackFcn(app, @DeteksiButtonPushed, true);
            app.DeteksiButton.Text = 'Deteksi';
            app.SelectPictureButton = uibutton(app.UIFigure, 'push');
            app.SelectPictureButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPictureButtonPushed, true);
            app.SelectPictureButton.Text = 'Select Picture';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TakePictureButton_2 = uibutton(app.UIFigure, 'push');
            app.TakePictureButton_2.ButtonPushedFcn = createCallbackFcn(app, @TakePictureButton_2Pushed, true);
            app.TakePictureButton_2.Text = 'Take Picture';
            app.DiagnosisTextAreaLabel = uilabel(app.UIFigure);
            app.DiagnosisTextAreaLabel.Text = 'Diagnosis';
            app.DiagnosisTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RapidTestAnalisis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end