classdef RapidTestAnalisis2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ManualTab                    matlab.ui.container.Tab
        Image2                       matlab.ui.control.Image
        Image_2                      matlab.ui.control.Image
        DeteksiManualButton          matlab.ui.control.Button
        RAPIDTESTANALYSISLabel       matlab.ui.control.Label
        IntensitasTextArea           matlab.ui.control.TextArea
        IntensitasTextAreaLabel      matlab.ui.control.Label
        DeteksiButton                matlab.ui.control.Button
        ConvertButton                matlab.ui.control.Button
        CropButton                   matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        SelectPictureButton          matlab.ui.control.Button
        UIAxes_1                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_5                     matlab.ui.control.UIAxes
        UIAxes_6                     matlab.ui.control.UIAxes
        UIAxes_7                     matlab.ui.control.UIAxes
        RapidBoxTab                  matlab.ui.container.Tab
        IDWIFIEditField              matlab.ui.control.EditField
        IDWIFIEditFieldLabel         matlab.ui.control.Label
        Image2_2                     matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        DiagnosisTextAreaLabel_3     matlab.ui.control.Label
        DeteksiManualButton_2        matlab.ui.control.Button
        IntensitasTextArea_2         matlab.ui.control.TextArea
        DeteksiButton_2              matlab.ui.control.Button
        ConvertButton_2              matlab.ui.control.Button
        CropButton_2                 matlab.ui.control.Button
        ResetButton_2                matlab.ui.control.Button
        TakePictureButton            matlab.ui.control.Button
        RAPIDTESTANALYSISLabel_2     matlab.ui.control.Label
        UIAxes_8                     matlab.ui.control.UIAxes
        UIAxes_9                     matlab.ui.control.UIAxes
        UIAxes_10                    matlab.ui.control.UIAxes
        UIAxes_11                    matlab.ui.control.UIAxes
        UIAxes_12                    matlab.ui.control.UIAxes
        UIAxes_13                    matlab.ui.control.UIAxes
        UIAxes_14                    matlab.ui.control.UIAxes
        AnalisisTab                  matlab.ui.container.Tab
        RegresiButton                matlab.ui.control.Button
        Image2_3                     matlab.ui.control.Image
        Image_3                      matlab.ui.control.Image
        ComputeButton_2              matlab.ui.control.Button
        NewTableButton               matlab.ui.control.Button
        SaveTableButton              matlab.ui.control.Button
        DeleteButton                 matlab.ui.control.Button
        RowEditField                 matlab.ui.control.NumericEditField
        RowEditFieldLabel            matlab.ui.control.Label
        ReadTableButton              matlab.ui.control.Button
        IntensityEditField           matlab.ui.control.NumericEditField
        IntensityEditFieldLabel      matlab.ui.control.Label
        ResultTextArea               matlab.ui.control.TextArea
        ResultTextAreaLabel          matlab.ui.control.Label
        IntensitasEditField          matlab.ui.control.NumericEditField
        IntensitasEditFieldLabel     matlab.ui.control.Label
        AddButton                    matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        ConcentrationEditField       matlab.ui.control.NumericEditField
        ConcentrationEditFieldLabel  matlab.ui.control.Label
        RAPIDTESTANALYSISLabel_3     matlab.ui.control.Label
        UIAxes_15                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Citra Asli
        img2 % Citra Sudah Tercrop dan Warna Terbalik
        img3 % Citra Kanal Hijau yang sudah di filter
        img4 % Citra Biner yang belum di crop
        img5 % Citra Kanal Hijau yang sudah di crop dari pengguna
        img6 % Citra app.img5 yang diubah ke Biner
        img7 % Citra Asli dari ESP32CAM
        img8 % Citra Sudah Tercrop dan Warna Terbalik dari ESP32CAM
        img9 % Citra app.img8 menjadi kanal hijau dari ESP32CAM
        img10 % Citra app.img9 menjadi Biner dari ESP32CAM
        img11 % Citra Kanal Hijau yang sudah di crop pengguna dari ESP32CAM
        img12 % Citra app.img11 yang diubah ke Biner dari ESP32CAM
        num_boxes
        mean_values
        rectangles
        properti_objek
        intensitas_cahaya
        result
        Baris
        T
        I
        f
        a
        b
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectPictureButton
        function SelectPictureButtonPushed(app, event)
            %%Jika ingin melakukan search di Komputer
           [file, path] = uigetfile('*.*');
           if isequal(file,0)
               figure(app.UIFigure);
               return;
           end
           app.img = imread(fullfile(path,file));
           imshow(app.img, "Parent", app.UIAxes_1)
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
           app.img = imcrop(app.img);
           imshow(app.img, "Parent", app.UIAxes_2)

           app.img2 = 255 - app.img;
           imshow(app.img2,"Parent", app.UIAxes_3);
           close all;
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            hh = fspecial('motion', 5);
            bb = imfilter(app.img2,hh); 
            app.img3 = bb(:,:,2); % Channel Hijau yang dibutuhkan 
            imshow (app.img3,'Parent',app.UIAxes_4);

            % Konversi gambar ke citra biner menggunakan ambang batas adaptif
            ambang_batas = graythresh(app.img3);
 
            % Gambar biner dari konversi channel hijau dan nilai ambang batas
            app.img4 = imbinarize(app.img3,ambang_batas);
            imshow(app.img4,'Parent',app.UIAxes_5);
        end

        % Button pushed function: DeteksiButton
        function DeteksiButtonPushed(app, event)
            imshow(app.img3);
            rect = getrect;

            % Ambil posisi kotak yang diambil oleh pengguna
            x1 = round(rect(1));
            y1 = round(rect(2));
            width = round(rect(3));
            height = round(rect(4));

            % Memotong bagian citra berdasarkan posisi yang dipilih
            app.img5 = imcrop(app.img3,[x1, y1, width, height]);
            imshow(app.img5, "Parent", app.UIAxes_6);

            % Mengidentifikasi properti objek dalam citra biner
            app.img6 = imbinarize(app.img5);
            app.properti_objek = regionprops(app.img6, 'BoundingBox');
            
            imshow (app.img5,"Parent",app.UIAxes_7);
            hold on
            app.result = {};
            app.IntensitasTextArea.Value = " ";
            for i = 1:numel(app.properti_objek)
                bounding_box = (app.properti_objek(i).BoundingBox);
                rectangle(app.UIAxes_7,'Position', bounding_box, 'EdgeColor', 'r', 'LineWidth', 1);
                x1 = round(bounding_box(1));
                y1 = round(bounding_box(2));
                width = round(bounding_box(3));
                height = round(bounding_box(4));
                x2 = x1 + width - 1;
                y2 = y1 + height - 1;
                selected_region = app.img3(y1:y2, x1:x2, :);
                app.mean_values(i) = mean2(selected_region);
                Intensitas = num2str(app.mean_values(i));
                if str2double(Intensitas) >=0 && str2double(Intensitas) <100
                   Hasil = "+1";
                elseif str2double(Intensitas) >=100 && str2double(Intensitas) <130
                   Hasil = "+2";
                elseif str2double(Intensitas) >=130
                   Hasil = "+3";
                end
                app.result{i,1}= char(append("Kotak",num2str(i),": ", Hasil," ","Nilai",num2str(i),": ", Intensitas));
                app.IntensitasTextArea.Value = app.result;
            end
            hold off
            close all;
        end

        % Button pushed function: DeteksiManualButton
        function DeteksiManualButtonPushed(app, event)
            imshow(app.img3);
            app.num_boxes = 3; % Ganti sesuai dengan jumlah kotak yang ingin Anda buat
            app.rectangles = cell(app.num_boxes, 1);
            
            for i = 1:app.num_boxes
            h = drawrectangle; % Anda dapat menarik kotak pada citra menggunakan mouse
            app.rectangles{i} = h;
            end
            
            % Hitung nilai-nilai citra dari setiap kotak yang dipilih
            app.mean_values = zeros(app.num_boxes, 1);

            imshow(app.img3,"Parent", app.UIAxes_7);
            hold on
            app.result = {};
            for i = 1:app.num_boxes
                pos = app.rectangles{i}.Position;
                x1 = round(pos(1));
                y1 = round(pos(2));
                width = round(pos(3));
                height = round(pos(4));
                x2 = x1 + width - 1;
                y2 = y1 + height - 1;
                rectangle(app.UIAxes_7,'Position', [x1, y1, width, height], 'EdgeColor', 'r', 'LineWidth', 1);
                selected_region = app.img3(y1:y2, x1:x2, :);
                app.mean_values(i) = mean2(selected_region);
                Intensitas = num2str(app.mean_values(i));
                if str2double(Intensitas) >=0 && str2double(Intensitas) <100
                   Hasil = "+1";
                elseif str2double(Intensitas) >=100 && str2double(Intensitas) <130
                   Hasil = "+2";
                elseif str2double(Intensitas) >=130
                   Hasil = "+3";
                end
                app.result{i,1}= char(append("Kotak",num2str(i),": ", Hasil," ","Nilai",num2str(i),": ", Intensitas));
                app.IntensitasTextArea.Value = app.result;
            end
            hold off
            close all;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes_1);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            cla(app.UIAxes_5);
            cla(app.UIAxes_6);
            cla(app.UIAxes_7);
            app.img = "";
            app.img2 = "";
            app.img3 = "";
            app.img4 = "";
            app.img5 = "";
            app.img6 = "";
            app.IntensitasTextArea.Value = " ";
        end

        % Button pushed function: TakePictureButton
        function TakePictureButtonPushed(app, event)
           WIFI = app.IDWIFIEditField.Value ;
           cam = ipcam(WIFI);
           %cam = ipcam('http://192.168.1.100:81/stream'); Jika harus
           %menggunakan link
           app.img7 = snapshot(cam);
           imshow(app.img7,"Parent",app.UIAxes_8);
        end

        % Button pushed function: CropButton_2
        function CropButton_2Pushed(app, event)
           app.img7 = imcrop(app.img7);
           imshow(app.img7, "Parent", app.UIAxes_9)

           app.img8 = 255 - app.img7;
           imshow (app.img8,"Parent", app.UIAxes_10)
           close all;
        end

        % Button pushed function: ConvertButton_2
        function ConvertButton_2Pushed(app, event)
            hh = fspecial('motion', 5);
            bb = imfilter(app.img8,hh); 
            app.img9 = bb(:,:,2); % Channel Hijau yang dibutuhkan 
            imshow (app.img9,'Parent',app.UIAxes_11);

            % Konversi gambar ke citra biner menggunakan ambang batas adaptif
            ambang_batas = graythresh(app.img9);
 
            % Gambar biner dari konversi channel hijau dan nilai ambang batas
            app.img10 = imbinarize(app.img9,ambang_batas);
            imshow(app.img10,'Parent',app.UIAxes_12);

        end

        % Button pushed function: DeteksiButton_2
        function DeteksiButton_2Pushed(app, event)
            imshow(app.img9);
            rect = getrect;

            % Ambil posisi kotak yang diambil oleh pengguna
            x1 = round(rect(1));
            y1 = round(rect(2));
            width = round(rect(3));
            height = round(rect(4));

            % Memotong bagian citra berdasarkan posisi yang dipilih
            app.img11 = imcrop(app.img9,[x1, y1, width, height]);
            imshow(app.img11, "Parent", app.UIAxes_13);

            % Mengidentifikasi properti objek dalam citra biner
            app.img12 = imbinarize(app.img11);
            app.properti_objek = regionprops(app.img12, 'BoundingBox');
            
            imshow (app.img11,"Parent",app.UIAxes_14);
            hold on
            app.result = {};
            app.IntensitasTextArea.Value = " ";
            for i = 1:numel(app.properti_objek)
                bounding_box = (app.properti_objek(i).BoundingBox);
                rectangle(app.UIAxes_14,'Position', bounding_box, 'EdgeColor', 'r', 'LineWidth', 1);
                x1 = round(bounding_box(1));
                y1 = round(bounding_box(2));
                width = round(bounding_box(3));
                height = round(bounding_box(4));
                x2 = x1 + width - 1;
                y2 = y1 + height - 1;
                selected_region = app.img9(y1:y2, x1:x2, :);
                app.mean_values(i) = mean2(selected_region);
                Intensitas = num2str(app.mean_values(i));
                if str2double(Intensitas) >=0 && str2double(Intensitas) <100
                   Hasil = "+1";
                elseif str2double(Intensitas) >=100 && str2double(Intensitas) <130
                   Hasil = "+2";
                elseif str2double(Intensitas) >=130
                   Hasil = "+3";
                end
                app.result{i,1}= char(append("Kotak",num2str(i),": ", Hasil," ","Nilai",num2str(i),": ", Intensitas));
                app.IntensitasTextArea_2.Value = app.result;
            end
            hold off
            close all;
        end

        % Button pushed function: DeteksiManualButton_2
        function DeteksiManualButton_2Pushed(app, event)
            imshow(app.img9);
            app.num_boxes = 3; % Ganti sesuai dengan jumlah kotak yang ingin Anda buat
            app.rectangles = cell(app.num_boxes, 1);
            
            for i = 1:app.num_boxes
            h = drawrectangle; % Anda dapat menarik kotak pada citra menggunakan mouse
            app.rectangles{i} = h;
            end
            
            % Hitung nilai-nilai citra dari setiap kotak yang dipilih
            app.mean_values = zeros(app.num_boxes, 1);

            imshow(app.img9,"Parent", app.UIAxes_14);
            hold on
            app.result = {};
            for i = 1:app.num_boxes
                pos = app.rectangles{i}.Position;
                x1 = round(pos(1));
                y1 = round(pos(2));
                width = round(pos(3));
                height = round(pos(4));
                x2 = x1 + width - 1;
                y2 = y1 + height - 1;
                rectangle(app.UIAxes_14,'Position', [x1, y1, width, height], 'EdgeColor', 'r', 'LineWidth', 1);
                selected_region = app.img9(y1:y2, x1:x2, :);
                app.mean_values(i) = mean2(selected_region);
                Intensitas = num2str(app.mean_values(i));
                if str2double(Intensitas) >=0 && str2double(Intensitas) <100
                   Hasil = "+1";
                elseif str2double(Intensitas) >=100 && str2double(Intensitas) <130
                   Hasil = "+2";
                elseif str2double(Intensitas) >=130
                   Hasil = "+3";
                end
                app.result{i,1}= char(append("Kotak",num2str(i),": ", Hasil," ","Nilai",num2str(i),": ", Intensitas));
                app.IntensitasTextArea_2.Value = app.result;
            end
            hold off
            close all;
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            cla(app.UIAxes_8);
            cla(app.UIAxes_9);
            cla(app.UIAxes_10);
            cla(app.UIAxes_11);
            cla(app.UIAxes_12);
            cla(app.UIAxes_13);
            cla(app.UIAxes_14);
            app.img8 = "";
            app.img9 = "";
            app.img10 = "";
            app.img11 = "";
            app.img12 = "";
            app.IntensitasTextArea_2.Value = " ";
        end

        % Button pushed function: NewTableButton
        function NewTableButtonPushed(app, event)
            Intensity={};
            Concentration={};
            app.T = table(Intensity, Concentration);
            app.UITable.Data = app.T;
            app.UITable.ColumnName = app.T.Properties.VariableNames;
            app.UITable.Data = app.T;
        end

        % Button pushed function: ReadTableButton
        function ReadTableButtonPushed(app, event)
              [file, path] = uigetfile('*.*');
              filename = [path file];
              app.T = readtable(filename);
              app.UITable.Data= app.T;

        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            % Get data from the UITable
            app.T = app.UITable.Data;

            % Prompt user for a new Excel file name
            [filename, filepath] = uiputfile('*.xlsx', 'Save Table Data as Excel File');
            if filename == 0
                return;  % User cancelled the operation
            end
            
            % Combine filepath and filename
            fullFilePath = fullfile(filepath, filename);

            % Save data to the new Excel file
            writetable((app.T), fullFilePath, 'Sheet', 1);
            msgbox(['Data saved to ', filename], 'Success');
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            Intensity = app.IntensityEditField.Value;
            Concentration = app.ConcentrationEditField.Value;
            nr = {Intensity, Concentration};
            app.UITable.Data = [app.T; nr];
            app.T = app.UITable.Data;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            number = app.RowEditField.Value;
            app.UITable.Data(number,:) = [];
            app.T = app.UITable.Data;
        end

        % Button pushed function: RegresiButton
        function RegresiButtonPushed(app, event)
            app.T = app.UITable.Data;
            x = table2array(app.T(:, "Intensity"));
            y = table2array(app.T(:, "Concentration"));
            sx = sum(x);
            sy = sum(y);
            mxx = x.*x;
            mxy = x.*y;
            smxx = sum(mxx);
            smxy = sum(mxy);
            N = length(x);
            app.a = ((N.*smxy)-(sx.*sy))./((N.*smxx)-(sx.^2));
            app.b = ((smxx.*sy)-(sx.*smxy))./((N.*smxx)-(sx.^2));
            app.f = app.a.*x + app.b;
            plot(app.UIAxes_15,x,y, "k*",x,app.f,"b-",'LineWidth',2);
        end

        % Button pushed function: ComputeButton_2
        function ComputeButtonPushed(app, event)
            app.I = app.IntensitasEditField.Value;
            Nilai = app.a .* app.I + app.b ;
            app.ResultTextArea.Value = num2str(Nilai);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.UIAxes_7 = uiaxes(app.ManualTab);
            title(app.UIAxes_7, 'Citra Deteksi')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_6 = uiaxes(app.ManualTab);
            title(app.UIAxes_6, 'Kanal Hijau Crop')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_5 = uiaxes(app.ManualTab);
            title(app.UIAxes_5, 'Biner')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_4 = uiaxes(app.ManualTab);
            title(app.UIAxes_4, 'Citra Kanal Hijau')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_3 = uiaxes(app.ManualTab);
            title(app.UIAxes_3, 'Citra Terbalik')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_2 = uiaxes(app.ManualTab);
            title(app.UIAxes_2, 'Citra Crop')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_1 = uiaxes(app.ManualTab);
            title(app.UIAxes_1, 'Citra')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.SelectPictureButton = uibutton(app.ManualTab, 'push');
            app.SelectPictureButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPictureButtonPushed, true);
            app.SelectPictureButton.Text = 'Select Picture';
            app.ResetButton = uibutton(app.ManualTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CropButton = uibutton(app.ManualTab, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.ConvertButton = uibutton(app.ManualTab, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.DeteksiButton = uibutton(app.ManualTab, 'push');
            app.DeteksiButton.ButtonPushedFcn = createCallbackFcn(app, @DeteksiButtonPushed, true);
            app.DeteksiButton.Text = 'Deteksi';
            app.IntensitasTextAreaLabel = uilabel(app.ManualTab);
            app.IntensitasTextAreaLabel.Text = 'Intensitas';
            app.IntensitasTextArea = uitextarea(app.ManualTab);
            app.RAPIDTESTANALYSISLabel = uilabel(app.ManualTab);
            app.RAPIDTESTANALYSISLabel.Text = 'RAPID TEST ANALYSIS';
            app.DeteksiManualButton = uibutton(app.ManualTab, 'push');
            app.DeteksiManualButton.ButtonPushedFcn = createCallbackFcn(app, @DeteksiManualButtonPushed, true);
            app.DeteksiManualButton.Text = 'Deteksi Manual';
            app.Image_2 = uiimage(app.ManualTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2023-08-10 at 11.01.31.jpeg');
            app.Image2 = uiimage(app.ManualTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2023-08-10 at 11.01.27.jpeg');
            app.RapidBoxTab = uitab(app.TabGroup);
            app.RapidBoxTab.Title = 'Rapid Box';
            app.UIAxes_14 = uiaxes(app.RapidBoxTab);
            title(app.UIAxes_14, 'Citra Deteksi')
            zlabel(app.UIAxes_14, 'Z')
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.UIAxes_13 = uiaxes(app.RapidBoxTab);
            title(app.UIAxes_13, 'Biner Crop')
            zlabel(app.UIAxes_13, 'Z')
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_12 = uiaxes(app.RapidBoxTab);
            title(app.UIAxes_12, 'Biner')
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.UIAxes_11 = uiaxes(app.RapidBoxTab);
            title(app.UIAxes_11, 'Citra Kanal Hijau')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_10 = uiaxes(app.RapidBoxTab);
            title(app.UIAxes_10, 'Citra Terbalik')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_9 = uiaxes(app.RapidBoxTab);
            title(app.UIAxes_9, 'Citra Crop')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_8 = uiaxes(app.RapidBoxTab);
            title(app.UIAxes_8, 'Citra')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.RAPIDTESTANALYSISLabel_2 = uilabel(app.RapidBoxTab);
            app.RAPIDTESTANALYSISLabel_2.Text = 'RAPID TEST ANALYSIS';
            app.TakePictureButton = uibutton(app.RapidBoxTab, 'push');
            app.TakePictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakePictureButtonPushed, true);
            app.TakePictureButton.Text = 'Take Picture';
            app.ResetButton_2 = uibutton(app.RapidBoxTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.CropButton_2 = uibutton(app.RapidBoxTab, 'push');
            app.CropButton_2.ButtonPushedFcn = createCallbackFcn(app, @CropButton_2Pushed, true);
            app.CropButton_2.Text = 'Crop';
            app.ConvertButton_2 = uibutton(app.RapidBoxTab, 'push');
            app.ConvertButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConvertButton_2Pushed, true);
            app.ConvertButton_2.Text = 'Convert';
            app.DeteksiButton_2 = uibutton(app.RapidBoxTab, 'push');
            app.DeteksiButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeteksiButton_2Pushed, true);
            app.DeteksiButton_2.Text = 'Deteksi';
            app.IntensitasTextArea_2 = uitextarea(app.RapidBoxTab);
            app.DeteksiManualButton_2 = uibutton(app.RapidBoxTab, 'push');
            app.DeteksiManualButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeteksiManualButton_2Pushed, true);
            app.DeteksiManualButton_2.Text = 'Deteksi Manual';
            app.DiagnosisTextAreaLabel_3 = uilabel(app.RapidBoxTab);
            app.DiagnosisTextAreaLabel_3.Text = 'Intensitas';
            app.Image = uiimage(app.RapidBoxTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2023-08-10 at 11.01.31.jpeg');
            app.Image2_2 = uiimage(app.RapidBoxTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2023-08-10 at 11.01.27.jpeg');
            app.IDWIFIEditFieldLabel = uilabel(app.RapidBoxTab);
            app.IDWIFIEditFieldLabel.Text = 'ID WIFI';
            app.IDWIFIEditField = uieditfield(app.RapidBoxTab, 'text');
            app.AnalisisTab = uitab(app.TabGroup);
            app.AnalisisTab.Title = 'Analisis';
            app.UIAxes_15 = uiaxes(app.AnalisisTab);
            title(app.UIAxes_15, 'Kurva Kalibrasi')
            xlabel(app.UIAxes_15, 'Intensitas')
            ylabel(app.UIAxes_15, 'Konsentrasi')
            zlabel(app.UIAxes_15, 'Z')
            app.RAPIDTESTANALYSISLabel_3 = uilabel(app.AnalisisTab);
            app.RAPIDTESTANALYSISLabel_3.Text = 'RAPID TEST ANALYSIS';
            app.ConcentrationEditFieldLabel = uilabel(app.AnalisisTab);
            app.ConcentrationEditFieldLabel.Text = 'Concentration';
            app.ConcentrationEditField = uieditfield(app.AnalisisTab, 'numeric');
            app.ConcentrationEditField.Limits = [0 Inf];
            app.UITable = uitable(app.AnalisisTab);
            app.UITable.ColumnName = {'Intensity'; 'Concentration'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.AddButton = uibutton(app.AnalisisTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.IntensitasEditFieldLabel = uilabel(app.AnalisisTab);
            app.IntensitasEditFieldLabel.Text = 'Intensitas';
            app.IntensitasEditField = uieditfield(app.AnalisisTab, 'numeric');
            app.ResultTextAreaLabel = uilabel(app.AnalisisTab);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.AnalisisTab);
            app.IntensityEditFieldLabel = uilabel(app.AnalisisTab);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.IntensityEditField = uieditfield(app.AnalisisTab, 'numeric');
            app.IntensityEditField.Limits = [0 Inf];
            app.ReadTableButton = uibutton(app.AnalisisTab, 'push');
            app.ReadTableButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTableButtonPushed, true);
            app.ReadTableButton.Text = 'Read Table';
            app.RowEditFieldLabel = uilabel(app.AnalisisTab);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.AnalisisTab, 'numeric');
            app.DeleteButton = uibutton(app.AnalisisTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.SaveTableButton = uibutton(app.AnalisisTab, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Text = 'Save Table';
            app.NewTableButton = uibutton(app.AnalisisTab, 'push');
            app.NewTableButton.ButtonPushedFcn = createCallbackFcn(app, @NewTableButtonPushed, true);
            app.NewTableButton.Text = 'New Table';
            app.ComputeButton_2 = uibutton(app.AnalisisTab, 'push');
            app.ComputeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton_2.Text = 'Compute';
            app.Image_3 = uiimage(app.AnalisisTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2023-08-10 at 11.01.31.jpeg');
            app.Image2_3 = uiimage(app.AnalisisTab);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2023-08-10 at 11.01.27.jpeg');
            app.RegresiButton = uibutton(app.AnalisisTab, 'push');
            app.RegresiButton.ButtonPushedFcn = createCallbackFcn(app, @RegresiButtonPushed, true);
            app.RegresiButton.Text = 'Regresi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RapidTestAnalisis2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end